classdef App_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DYNAblast20UIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        FileofLayersMenu                matlab.ui.container.Menu
        FileofParametersMenu            matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutSoftwareMenu               matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        StructureTab                    matlab.ui.container.Tab
        btn_duplicate                   matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        tbl_layers                      matlab.ui.control.Table
        btn_plus                        matlab.ui.control.Button
        btn_minus                       matlab.ui.control.Button
        BoundaryConditionsLabel         matlab.ui.control.Label
        SupportLabel                    matlab.ui.control.Label
        cmb_support                     matlab.ui.control.DropDown
        GeometricCharacteristicsLabel   matlab.ui.control.Label
        txt_a                           matlab.ui.control.NumericEditField
        txt_b                           matlab.ui.control.NumericEditField
        txt_shearfactor                 matlab.ui.control.NumericEditField
        LengthatxaxismLabel             matlab.ui.control.Label
        LengthatyaxismLabel             matlab.ui.control.Label
        ShearFactorLabel                matlab.ui.control.Label
        PointofAnalysisLabel            matlab.ui.control.Label
        txt_xi                          matlab.ui.control.NumericEditField
        txt_yi                          matlab.ui.control.NumericEditField
        AnalysispointxLabel             matlab.ui.control.Label
        AnalysispointyLabel             matlab.ui.control.Label
        DampingsCoefficientLabel        matlab.ui.control.Label
        txt_c                           matlab.ui.control.NumericEditField
        LoadingandAnalysisTab           matlab.ui.container.Tab
        AnalysisPanel                   matlab.ui.container.Panel
        StressStrainSwitch              matlab.ui.control.Switch
        AnalysisofStressandStrainLabel  matlab.ui.control.Label
        MassofTNTLabel                  matlab.ui.control.Label
        btn_RunButton_Adv               matlab.ui.control.Button
        CaseofAnalysisLabel             matlab.ui.control.Label
        cmb_case                        matlab.ui.control.DropDown
        txt_mass_case2                  matlab.ui.control.Label
        txt_interval_case2              matlab.ui.control.Label
        txt_interval                    matlab.ui.control.NumericEditField
        txt_W_final                     matlab.ui.control.NumericEditField
        Image3                          matlab.ui.control.Image
        DynamicSwitch                   matlab.ui.control.Switch
        NegativePhaseSwitch             matlab.ui.control.Switch
        btn_RunButton_General           matlab.ui.control.Button
        TypeofAnalysisLabel             matlab.ui.control.Label
        cmb_structure_theory            matlab.ui.control.DropDown
        NegativePhaseLabel              matlab.ui.control.Label
        DynamicAnalysisLabel            matlab.ui.control.Label
        TimeofAnalysisLabel             matlab.ui.control.Label
        txt_timeanalysis                matlab.ui.control.NumericEditField
        timesLabel                      matlab.ui.control.Label
        ExplosionLoadingPanel           matlab.ui.container.Panel
        TypeDistance                    matlab.ui.container.ButtonGroup
        ScaledDistanceZButton           matlab.ui.control.RadioButton
        DistanceRButton                 matlab.ui.control.RadioButton
        TypeofDistanceLabel             matlab.ui.control.Label
        txt_R                           matlab.ui.control.NumericEditField
        DistanceRmLabel                 matlab.ui.control.Label
        txt_decay                       matlab.ui.control.NumericEditField
        DecayCoefficientLabel           matlab.ui.control.Label
        TypeExplosion                   matlab.ui.container.ButtonGroup
        HemisphericalButton             matlab.ui.control.RadioButton
        SphericalButton                 matlab.ui.control.RadioButton
        TypeofExplosionLabel            matlab.ui.control.Label
        CharacteristicsLabel            matlab.ui.control.Label
        TotalMasskgLabel                matlab.ui.control.Label
        ScaledDistanceZkgm13Label       matlab.ui.control.Label
        MaxSobrepressurePaLabel         matlab.ui.control.Label
        MaxUnderpressurePaLabel         matlab.ui.control.Label
        PositivetimesLabel              matlab.ui.control.Label
        PositiveImpulsePasLabel         matlab.ui.control.Label
        NegativeImpulsePasLabel         matlab.ui.control.Label
        txt_W                           matlab.ui.control.NumericEditField
        txt_Z                           matlab.ui.control.NumericEditField
        txt_pmax                        matlab.ui.control.NumericEditField
        txt_pmin                        matlab.ui.control.NumericEditField
        txt_td                          matlab.ui.control.NumericEditField
        txt_id                          matlab.ui.control.NumericEditField
        txt_im                          matlab.ui.control.NumericEditField
        TypeofEquationLabel             matlab.ui.control.Label
        cmb_blast_equation              matlab.ui.control.DropDown
    end

    
    % General Properties

    properties (Access = public)

        plate           Class_Plate
        layer           Class_Layer
        tnt             Class_TNT
        analysis        Class_Analysis
        adv_analysis    Class_AdvAnalysis
        pmt             Class_Parameters
        blast           Class_Blast
        effort          Class_Effort
        energy          Class_Energy
        solution        Class_Solution
        solstatic       Class_SolutionStatic
        soldynamic      Class_SolutionDynamic
        control         Class_Control

        % Vectors for Class_Control
        value_control       = [];

        % Vectors for constructor solution
        value_plate         = [];
        value_layer         = [];
        value_tnt           = [];
        value_analysis      = [];
        value_advanalysis   = [];
        value_pmt           = [];

        % Vectors for solution
        value_effort        = [];
        value_energy        = [];
        value_solution      = [];
        value_result        = [];
        value_resultadv     = [];

        % Parameters to pass pieces of information between windows
        appgraph
        appAdvanced

    end

    methods (Access = public)

        % Function to verify the values of user's options
        function [support, blast_equation, ...
                type_explosion, theory, negative, ...
                analysis_dynamic, advanced_analysis, stress_strain, ...
                type_distance] = ...
                Components(~, ap1, ap2, ap3, ap4, ap5, ap6, ap7, ap8, ap9)

            % Verify the type of support
            switch ap1
                case 'SS1'
                    support = 1;
                case 'SS2'
                    support = 2;
                case 'CCCC'
                    support = 3;
            end

            % Verify blast wave data base
            switch ap2
                case 'Friedlander + Cubic Equation - Rigby Calibration'
                    blast_equation = 1;
                case 'Friedlander + Cubic Equation (calculate tm) - Rigby Calibration'
                    blast_equation = 2;
                case 'Friedlander + Cubic Equation (calculate tm) - Reis Calibration'
                    blast_equation = 3;
                case 'Friedlander + Cubic Equation - Experimental Data'
                    blast_equation = 4;
                case 'Expanded Friedlander - Rigby Calibration'
                    blast_equation = 5;
                case 'Expanded Friedlander - Experimental Data'
                    blast_equation = 6;
            end

            % Verify the type of explosion
            switch ap3
                case 'Hemispherical'
                    type_explosion = 1;
                case 'Spherical'
                    type_explosion = 2;
            end

            % Verify the type of analysis the user chose
            switch ap4
                case 'CLPT'
                    theory = 1;
                case 'FSPT'
                    theory = 2;
                case 'HSPT'
                    theory = 3;
                case 'TvK'
                    theory = 4;
            end

            % Verify the consideration of negative phase
            switch ap5
                case 'Off'
                    negative = 0;
                case 'On'
                    negative = 1;
            end

            % Verify if dynamic analysis is on or off
            switch ap6
                case 'Off'
                    analysis_dynamic = 0;
                case 'On'
                    analysis_dynamic = 1;
            end

            % Avaliating the advanced analysis
            switch ap7
                case 'Case 1 - Z x uz / h'
                    advanced_analysis = 1;
                case 'Case 2 - W x uz / h'
                    advanced_analysis = 2;
                case 'Case 3 - R x uz / h'
                    advanced_analysis = 3;
                case 'Case 4 - td / TNL x FAD - Varying W'
                    advanced_analysis = 4;
                case 'Case 5 - td / TNL x FAD - Varying Z'
                    advanced_analysis = 5;
                case 'Case 6 - td / TNL x FAD - Varying R'
                    advanced_analysis = 6;
                case 'Case 7 - td / TNL x uz / h'
                    advanced_analysis = 7;
                case 'Case 8 - General Equation'
                    advanced_analysis = 8;
                case 'Case 9 - 3D Graphic - Surface'
                    advanced_analysis = 9;
            end

            % Verify the analysis of stress and strain
            switch ap8
                case 'Off'
                    stress_strain = 0;
                case 'On'
                    stress_strain = 1;
            end

            % Verify the distance
            switch ap9
                case 'Distance R'
                    type_distance = 1;
                case 'Scaled Distance Z'
                    type_distance = 2;
            end

        end

        % ---------------------------------------------------------- %

        % Function to verify the values of user's options
        function blast_equation = BlastComponents(~, ap2)

            % Verify blast wave data base
            switch ap2
                case 'Friedlander + Cubic Equation - Rigby Calibration'
                    blast_equation = 1;
                case 'Friedlander + Cubic Equation (calculate tm) - Rigby Calibration'
                    blast_equation = 2;
                case 'Friedlander + Cubic Equation (calculate tm) - Reis Calibration'
                    blast_equation = 3;
                case 'Friedlander + Cubic Equation - Experimental Data'
                    blast_equation = 4;
                case 'Expanded Friedlander - Rigby Calibration'
                    blast_equation = 5;
                case 'Expanded Friedlander - Experimental Data'
                    blast_equation = 6;
            end

        end

        % ---------------------------------------------------------- %

        % Function to verify the values of user's options
        function advanced_analysis = AdvancedComponents(~,ap7)
            
            % Verify advanced parameters
            switch ap7
                case 'Case 1 - Z x uz / h'
                    advanced_analysis = 1;
                case 'Case 2 - W x uz / h'
                    advanced_analysis = 2;
                case 'Case 3 - R x uz / h'
                    advanced_analysis = 3;
                case 'Case 4 - td / TNL x FAD - Varying W'
                    advanced_analysis = 4;
                case 'Case 5 - td / TNL x FAD - Varying Z'
                    advanced_analysis = 5;
                case 'Case 6 - td / TNL x FAD - Varying R'
                    advanced_analysis = 6;
                case 'Case 7 - td / TNL x uz / h'
                    advanced_analysis = 7;
                case 'Case 8 - General Equation'
                    advanced_analysis = 8;
                case 'Case 9 - 3D Graphic - Surface'
                    advanced_analysis = 9;
            end

        end

        % ---------------------------------------------------------- %

        % Function to verify the which distance the user choose
        function type_distance = DistanceComponent (~, ap9)
            
            switch ap9
                case "Distance R"
                    type_distance    = 1;
                case "Scaled Distance Z"
                    type_distance    = 2;
            end

        end

        % Function to enable/disable components
        function EnableComponents(app, blast_equation, type_distance)

            if (blast_equation == 1 || blast_equation == 2 || ...
                blast_equation == 3 || blast_equation == 5)
                
                if type_distance == 1
                    app.txt_W.Enable        = "on";
                    app.txt_Z.Enable        = "off";
                    app.txt_R.Enable        = "on";
                else
                    app.txt_W.Enable        = "on";
                    app.txt_Z.Enable        = "on";
                    app.txt_R.Enable        = "off";
                end

                app.txt_pmax.Enable     = "off";
                app.txt_pmin.Enable     = "off";
                app.txt_td.Enable       = "off";
                app.txt_id.Enable       = "off";
                app.txt_im.Enable       = "off";
                app.txt_decay.Enable    = "off";

            else

                app.txt_W.Enable        = "off";
                app.txt_Z.Enable        = "off";
                app.txt_R.Enable        = "off";

                app.txt_pmax.Enable     = "on";
                app.txt_pmin.Enable     = "on";
                app.txt_td.Enable       = "on";
                app.txt_id.Enable       = "on";
                app.txt_im.Enable       = "on";
                app.txt_decay.Enable    = "on";

            end

        end

        % ---------------------------------------------------------- %

        % Function to enable/disable components
        function EnableAdvancedComponents(app, advanced_parameter)

            if (advanced_parameter == 2 || advanced_parameter == 4 || ...
                    advanced_parameter == 9)
                app.txt_W_final.Enable  = "on";
                app.txt_interval.Enable = "on";

            elseif (advanced_parameter == 8)
                app.txt_interval.Value  = 10;
                app.txt_interval.Enable = "off";
                app.txt_W_final.Enable  = "on";
            else
                app.txt_W_final.Enable  = "off";
                app.txt_interval.Enable = "off";
            end

        end

        % ---------------------------------------------------------- %

        % Function to verify the components
        function bool = VerifyComponents(app, blast_equation)

            bool        = true;

            if isempty(app.tbl_layers.Data)
                msgbox('The table is empty!','Information','warn');
                bool    = false;
                return
            end

            if (app.txt_a.Value == 0)
                msgbox('Length "a" is equal to zero!', ...
                    'Information', 'warn');
                bool    = false;
                return
            end

            if (app.txt_b.Value == 0)
                msgbox('Length "b" is equal to zero!', ...
                    'Information', 'warn');
                bool    = false;
                return
            end

            if (blast_equation == 5 || blast_equation == 6)

                if (app.txt_pmax.Value == 0 || ...
                        app.txt_td.Value == 0)

                    msgbox('Maximum sobrepressure and/or positive time are zero!', ...
                        'Information', 'warn');
                    bool    = false;
                    return

                end

            end

            if (blast_equation == 1 || blast_equation == 2 || ...
                    blast_equation == 3 || blast_equation == 4)
                
                if (app.txt_W.Value == 0 || app.txt_Z.Value == 0 || ...
                        app.txt_R.Value == 0)

                    msgbox('Mass of TNT and/or Scaled distance are zero!', ...
                    'Information', 'warn');
                    bool    = false;
                    return

                end

            end

            if (app.txt_timeanalysis.Value == 0)

                msgbox('Time of analysis is equal to zero!',  ...
                    'Information', 'warn');
                bool    = false;
                return

            end

        end

        % ---------------------------------------------------------- %

        % Function to verify the components
        function bool = VerifyAdvancedComponents(app, advanced_parameter)

            bool        = true;

            if (advanced_parameter == 2 || advanced_parameter == 4 || ...
                    advanced_parameter == 9)

                if (app.txt_W_final.Value == 0 || ...
                    app.txt_interval.Value == 0)
    
                    msgbox('Final mass of TNT and/or Interval is empety!', ...
                        'Information','warn');
                    bool    = false;
                    return

                end

            end

        end

        % ---------------------------------------------------------- %

        % Function to verify the components of input data
        function VerifyInputData(app, support, blast_equation, ...
    		    type_explosion, type_distance, theory, negative, ...
                analysis_dynamic, stress_strain, advanced_analysis)

            switch support
                case 1
                    app.cmb_support.Value 	= 'SS1';
                case 2
                    app.cmb_support.Value 	= 'SS2';
                case 3
                    app.cmb_support.Value 	= 'CCCC';
                otherwise
                    app.cmb_support.Value 	= 'SS1';
            end

            switch blast_equation
                case 1
                    app.cmb_blast_equation.Value 	= ...
                        'Friedlander + Cubic Equation - Rigby Calibration';
                case 2
                    app.cmb_blast_equation.Value 	= ...
                        'Friedlander + Cubic Equation (calculate tm) - Rigby Calibration';
                case 3
                    app.cmb_blast_equation.Value 	= ...
                        'Friedlander + Cubic Equation (calculate tm) - Reis Calibration';
                case 4
                    app.cmb_blast_equation.Value 	= ...
                        'Friedlander + Cubic Equation - Experimental Data';
                case 5
                    app.cmb_blast_equation.Value 	= ...
                        'Expanded Friedlander - Rigby Calibration';
                case 6
                    app.cmb_blast_equation.Value 	= ...
                        'Expanded Friedlander - Experimental Data';
                otherwise
                    app.cmb_blast_equation.Value 	= ...
                        'Friedlander + Cubic Equation - Rigby Calibration';
            end

            switch type_explosion
                case 1
                    app.TypeExplosion.SelectedObject.Text 	= 'Hemispherical';
                case 2
                    app.TypeExplosion.SelectedObject.Text 	= 'Spherical';
                otherwise
                    app.TypeExplosion.SelectedObject.Text 	= 'Hemispherical';
            end

            switch type_distance
                case 1
                    app.TypeDistance.SelectedObject.Text    = "Distance R";
                case 2
                    app.TypeDistance.SelectedObject.Text    = "Scaled Distance Z";
                otherwise
                    app.TypeDistance.SelectedObject.Text    = "Scaled Distance Z";                   
            end

            switch theory
                case 1
                    app.cmb_structure_theory.Value = 'CLPT';
                case 2
                    app.cmb_structure_theory.Value = 'FSPT';
                case 3
                    app.cmb_structure_theory.Value = 'HSPT';
                case 4
                    app.cmb_structure_theory.Value = 'TvK';
                otherwise
                    app.cmb_structure_theory.Value = 'HSPT';
            end

            switch negative
                case 1
                    app.NegativePhaseSwitch.Value = 'Off';
                case 2
                    app.NegativePhaseSwitch.Value = 'On';
                otherwise
                    app.NegativePhaseSwitch.Value = 'On';
            end

            switch analysis_dynamic
                case 0
                    app.DynamicSwitch.Value = 'Off';
                case 1
                    app.DynamicSwitch.Value = 'On';
                otherwise
                    app.DynamicSwitch.Value = 'On';
            end

            switch stress_strain
                case 0
                    app.StressStrainSwitch.Value 	= 'Off';
                case 1
                    app.StressStrainSwitch.Value 	= 'On';
                otherwise
                    app.StressStrainSwitch.Value 	= 'Off';
            end

            switch advanced_analysis
                case 1
                    app.cmb_case.Value 				= 'Case 1 - Z x uz / h';
                case 2
                    app.cmb_case.Value 				= 'Case 2 - W x uz / h';
                case 3
                    app.cmb_case.Value 				= 'Case 3 - R x uz / h';
                case 4
                    app.cmb_case.Value 				= 'Case 4 - td / TNL x FAD - Varying W';
                case 5
                    app.cmb_case.Value 				= 'Case 5 - td / TNL x FAD - Varying Z';
                case 6
                    app.cmb_case.Value 				= 'Case 6 - td / TNL x FAD - Varying R';
                case 7
                    app.cmb_case.Value 				= 'Case 7 - td / TNL x uz / h';
                case 8
                    app.cmb_case.Value 				= 'Case 8 - General Equation';
                case 9
                    app.cmb_case.Value 				= 'Case 9 - 3D Graphic - Surface';
                otherwise
                    app.cmb_case.Value 				= 'Case 1 - Z x uz / h';
            end

        end
		
        % ---------------------------------------------------------- %
        
        % Function to create save file
        function saveFile(app,filename)

            % Open file with given file name for writing
            fid = fopen(filename,'wt+');

            % Support Condition
            fprintf(fid,'*Support Condition* \n');
            fprintf(fid, 'Support = %s\n', app.cmb_support.Value);
            fprintf(fid,'\n');

            % Damping Coefficient
            fprintf(fid,'*Damping Coefficient* \n');
            fprintf(fid, 'Coefficient = %2.5f\n', app.txt_c.Value);
            fprintf(fid,'\n');

            % Geometric Characteristics
            fprintf(fid,'*Geometric Characteristics* \n');
            fprintf(fid, 'a = %2.3f \n', app.txt_a.Value);
            fprintf(fid, 'b = %2.3f \n', app.txt_b.Value);
            fprintf(fid, 'Shear Factor = %2.3f \n', app.txt_shearfactor);
            fprintf(fid,'\n');

            % Point of Analysis
            fprintf(fid,'*Point of Analysis* \n');
            fprintf(fid, 'xi = %2.3f \n', app.txt_xi.Value);
            fprintf(fid, 'yi = %2.3f \n', app.txt_yi.Value);
            fprintf(fid,'\n');

            fprintf(fid, '*Table of Layers*\n');
            newmatrix   = cell2mat(app.tbl_layers.Data);
            size_m      = size(newmatrix);
            for i = 1 : size_m(1)
                matrix = sprintf('%.5f   %.5f   %.5f   %.5f   %.5f   %.5f   %.5f   %.5f   %.5f   %.5f   %.5f \n', ...
                            newmatrix(i,1), newmatrix(i,2), newmatrix(i,3), ...
                            newmatrix(i,4), newmatrix(i,5), newmatrix(i,6), ...
                            newmatrix(i,7), newmatrix(i,8), newmatrix(i,9), ...
                            newmatrix(i,10), newmatrix(i,11));
                
                fprintf(fid, matrix);

            end
            fprintf(fid,'\n');

            % TNT
            fprintf(fid,'*TNT* \n');
            fprintf(fid, 'Type of Explosion = %s\n', ...
                app.TypeExplosion.SelectedObject.Text);
            fprintf(fid, 'Equation = %s\n', ...
                app.cmb_blast_equation.Value);

            fprintf(fid, 'W = %2.10f\n', app.txt_W.Value);
            fprintf(fid, 'Z = %2.10f\n', app.txt_Z.Value);
            fprintf(fid, 'R = %2.10f\n', app.txt_R.Value);
            fprintf(fid, 'pmax = %2.10f\n', app.txt_pmax.Value);
            fprintf(fid, 'pmin = %2.10f\n', app.txt_pmin.Value);
            fprintf(fid, 'td = %2.10f\n', app.txt_td.Value);
            fprintf(fid, 'id = %2.10f\n', app.txt_id.Value);
            fprintf(fid, 'im = %2.10f\n', app.txt_im.Value);
            fprintf(fid,'\n');

            % Analysis
            fprintf(fid,'*General Analysis* \n');
            fprintf(fid, 'Theory = %s\n', ...
                app.cmb_structure_theory.Value);
            fprintf(fid, 'Time Analysis = %2.10f\n', ...
                app.txt_timeanalysis.Value);
            fprintf(fid, 'Negative Phase = %s\n', ...
                app.NegativePhaseSwitch.Value);
            fprintf(fid, 'Dynamic = %s\n', app.DynamicSwitch.Value);
            fprintf(fid, 'Analysis of Stress and Strain = %s\n', ...
                app.StressStrainSwitch.Value);
            fprintf(fid,'\n');

            fprintf(fid,'*Advanced Analysis* \n');
            fprintf(fid, 'Case = %s\n', app.cmb_case.Value);
            fprintf(fid, 'Final TNT Mass = %2.10f\n', ...
                app.txt_W_final.Value);
            fprintf(fid, 'Number of intervals = %d\n', ...
                app.txt_interval.Value);
            fprintf(fid,'\n');

            fclose(fid);

        end

        % ---------------------------------------------------------- %

        % Function to verify the components of input data
        function openFileLayers(app, filename)

            M               = readmatrix(filename);
            M_size          = size(M);

            vector_logical  = isnan(M);
            logical_size    = size(vector_logical);

            if M_size(2) ~= 11
                msgbox('Please, verify the if the matrix has 11 columns.', 'Information', 'warn');
                return;
            end

            for i = 1 : logical_size(1)
                for j = 1 : logical_size(2)
                    if vector_logical(i,j) == 1
                        msgbox('Please, verify the matrix. There is, at least, one column incompleted.', 'Information', 'warn');
                        return;
                    end
                end
            end
            
            finalTable = array2table(M);
            app.tbl_layers.Data = finalTable;

        end

        % ---------------------------------------------------------- %
        
        % Function to open file
        function openFileParameters(app,filename)

            % Open file with given file name for reading
            fid = fopen(filename,'rt');

            % Reading value
            M               = readmatrix(filename);
            matrix_size     = size(M);

            if matrix_size(2) > 1
                msgbox('Please, verify if the file chosen is the correct one.', 'Information', 'warn');
                return;
            end
    
            if matrix_size(1) ~= 25
                msgbox('Please, verify if there are 25 rows into the file or there is a letter in any row.', 'Information', 'warn');
                return;
            end

            % First tab - Plate
            support                			= fscanf(fid,'%f',1);
            app.txt_c.Value 				= abs(fscanf(fid,'%f',1));
            app.txt_a.Value         		= abs(fscanf(fid,'%f',1));
            app.txt_b.Value      			= abs(fscanf(fid,'%f',1));
            app.txt_shearfactor.Value   = abs(fscanf(fid,'%f',1));
            app.txt_xi.Value        		= abs(fscanf(fid,'%f',1));
            app.txt_yi.Value         		= abs(fscanf(fid,'%f',1));

            % Second tab - Explosion Loading
            blast_equation 					= fscanf(fid,'%f',1);
            app.txt_W.Value         		= abs(fscanf(fid,'%f',1));
            app.txt_Z.Value         		= abs(fscanf(fid,'%f',1));
            app.txt_R.Value         		= abs(fscanf(fid,'%f',1));
            app.txt_pmax.Value      		= abs(fscanf(fid,'%f',1));
            app.txt_pmin.Value      		= abs(fscanf(fid,'%f',1));
            app.txt_td.Value        		= abs(fscanf(fid,'%f',1));
            app.txt_id.Value        		= abs(fscanf(fid,'%f',1));
            app.txt_im.Value        		= abs(fscanf(fid,'%f',1));
            app.txt_decay.Value 			= abs(fscanf(fid,'%f',1));
            type_explosion                  = abs(fscanf(fid,'%f',1));

            % Second tab - Analysis
            theory 							= fscanf(fid,'%f',1);
            app.txt_timeanalysis.Value 		= abs(fscanf(fid,'%f',1));
            negative 					 	= fscanf(fid,'%f',1);
            analysis_dynamic 				= fscanf(fid,'%f',1);
            stress_strain 					= fscanf(fid,'%f',1);

            % Second tab - Advanced Analysis
            advanalysis 					= fscanf(fid,'%f',1);
            app.txt_W_final.Value 			= abs(fscanf(fid,'%f',1));
            app.txt_interval.Value 			= fscanf(fid,'%f',1);
            
            VerifyInputData(app, support, blast_equation, ...
    		    type_explosion, theory, negative, analysis_dynamic, ...
    		    stress_strain, advanalysis);

            fclose(fid);

        end
		
        % ---------------------------------------------------------- %
        
        % Function to create a save file for general equation
        function saveEquation(~,filename, A, B, C, D, E, F)
        
            % ----------------------------------------------------------- %
            
            % Open file with given file name for writing
            
            fid = fopen(filename,'wt+');
            
            % ----------------------------------------------------------- %
            
            % Writing principal equation
            
            fprintf(fid,'General Equation for curves \n');
            fprintf(fid, ...
                'u_z / h = A * Z^5 + B * Z^4 + C * Z^3 + D * Z^2 + E * Z + F \n');
            fprintf(fid,'\n');
            
            % ----------------------------------------------------------- %
            
            % Writing coefficient equation    
            
            fprintf(fid, 'Coefficients Equations \n');
            fprintf(fid, ['A = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [A(1),A(2),A(3),A(4),A(5),A(6),A(7)]);
            fprintf(fid, ['B = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [B(1),B(2),B(3),B(4),B(5),B(6),B(7)]);
            fprintf(fid, ['C = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [C(1),C(2),C(3),C(4),C(5),C(6),C(7)]);
            fprintf(fid, ['D = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [D(1),D(2),D(3),D(4),D(5),D(6),D(7)]);
            fprintf(fid, ['E = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [E(1),E(2),E(3),E(4),E(5),E(6),E(7)]);
            fprintf(fid, ['F = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [F(1),F(2),F(3),F(4),F(5),F(6),F(7)]);
            fprintf(fid,'\n');
            
            fprintf(fid,'Legend: \n');
            fprintf(fid,'Z: Scalar Distance [m / (kg)^(1/3)] \n');
            fprintf(fid,'W: TNT Mass [kg] \n');
                        
            % ----------------------------------------------------------- %
            
            % Close file
            
            fclose(fid);
        
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Block components
            blast_equation  = app.BlastComponents(app.cmb_blast_equation.Value);
            type_distance   = app.DistanceComponent(app.TypeDistance.SelectedObject.Text);
            EnableComponents(app, blast_equation, type_distance);
            advanced_parameter = app.AdvancedComponents(app.cmb_case.Value);
            EnableAdvancedComponents(app, advanced_parameter);
        end

        % Cell edit callback: tbl_layers
        function tbl_layersCellEdit(app, event)
            app.tbl_layers.ColumnEditable = [false, true, true, true, ...
                true, true, true, true, true, true, true];
            app.tbl_layers.ColumnFormat = {'numeric' 'numeric' 'numeric' ...
                'numeric' 'numeric' 'numeric' 'numeric' 'numeric'...
                 'numeric' 'numeric'};
            app.tbl_layers.RowName = 'numbered'; 
        end

        % Value changed function: cmb_blast_equation
        function cmb_blast_equationValueChanged(app, event)
            blast_equation = app.BlastComponents(app.cmb_blast_equation.Value);
            type_distance   = app.DistanceComponent(app.TypeDistance.SelectedObject.Text);
            EnableComponents(app, blast_equation, type_distance);
        end

        % Value changed function: cmb_case
        function cmb_caseValueChanged(app, event)
            advanced_parameter = app.AdvancedComponents(app.cmb_case.Value);
            EnableAdvancedComponents(app, advanced_parameter);
        end

        % Selection changed function: TypeDistance
        function TypeDistanceSelectionChanged(app, event)
            blast_equation = app.BlastComponents(app.cmb_blast_equation.Value);
            type_distance   = app.DistanceComponent(app.TypeDistance.SelectedObject.Text);
            EnableComponents(app, blast_equation, type_distance);
        end

        % Value changed function: txt_Z
        function txt_ZValueChanged(app, event)
            type_distance       = app.DistanceComponent(app.TypeDistance.SelectedObject.Text);
            
            W                   = app.txt_W.Value;

            if type_distance == 1
                R               = app.txt_R.Value;
                Z               = R / W^(1/3);
                app.txt_Z.Value = Z;
            else
                Z               = app.txt_Z.Value;
                R               = Z * W^(1/3);
                app.txt_R.Value = R;
            end
            
        end

        % Value changed function: txt_R
        function txt_RValueChanged(app, event)
            type_distance       = app.DistanceComponent(app.TypeDistance.SelectedObject.Text);
            
            W                   = app.txt_W.Value;

            if type_distance == 1
                R               = app.txt_R.Value;
                Z               = R / W^(1/3);
                app.txt_Z.Value = Z;
            else
                Z               = app.txt_Z.Value;
                R               = Z * W^(1/3);
                app.txt_R.Value = R;
            end
            
        end

        % Button pushed function: btn_plus
        function btn_plusButtonPushed(app, event)
            % First is necessary to verificate if the table is empty
            verification = isempty(app.tbl_layers.Data);

            if verification == true
                % First Material
                rowdata     = {1, 3.3333e-04, 1620, 0, 0.318, 0.0217, ...
                                135140000000, 9240000000, ...
                                6270000000, 6270000000, 0};

            else
                row         = height(app.tbl_layers.Data);

                if row == 1 || row == 4
                    % Second Material
                    rowdata = {row + 1, 3.3333e-04, 1910, 0, 0.115, 0.115, ...
                                23370000000, 23370000000, ...
                                5230000000, 5230000000, 5230000000};
            
                elseif row == 2 || row == 5
                    % Third Material
                    rowdata = {row + 1, 3.3333e-04, 1450, 0, 0.059, 0.059, ...
                                62740000000, 62740000000, ...
                                4370000000, 4370000000, 4370000000};

                elseif row == 3
                    % First Material
                    rowdata = {row + 1, 3.3333e-04, 1620, 0, 0.318, 0.0217, ...
                                135140000000, 9240000000, ...
                                6270000000, 6270000000, 0};

                else
                    rowdata = {row + 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0};
                end

            end

            % Update table data
            app.tbl_layers.Data = [app.tbl_layers.Data; rowdata];
        end

        % Button pushed function: btn_minus
        function btn_minusButtonPushed(app, event)
            % First is necessary to verificate if the table is empty
            verification = isempty(app.tbl_layers.Data);
            
            % Creating a new array that is based on the number of rows
            if verification == true
                msgbox('The table is empty!','Information','warn');
            else
                row     = height(app.tbl_layers.Data);
                app.tbl_layers.Data(row,:) = [];
            end
            
        end

        % Button pushed function: btn_duplicate
        function btn_duplicateButtonPushed(app, event)
            % First is necessary to verificate if the table is empty
            verification = isempty(app.tbl_layers.Data);

            if verification == true
                msgbox('The table is empty!','Information','warn');
            else
                row                 = height(app.tbl_layers.Data);
                matrix_data         = cell2mat(app.tbl_layers.Data);
                
                rowdata = {row + 1, ...
                           matrix_data(row,2), ...
                           matrix_data(row,3), ...
                           matrix_data(row,4), ...
                           matrix_data(row,5), ...
                           matrix_data(row,6), ...
                           matrix_data(row,7), ...
                           matrix_data(row,8), ...
                           matrix_data(row,9), ...
                           matrix_data(row,10), ...
                           matrix_data(row,11)};

                % Update table data
                app.tbl_layers.Data = [app.tbl_layers.Data; rowdata];

            end

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uiputfile(filterspec,'Dynablast Program');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                saveFile(app,fullname)
            end
        end

        % Menu selected function: FileofLayersMenu
        function FileofLayersMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uigetfile(filterspec,'Dynablast 2.0 Program');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                openFileLayers(app,fullname);
            end
        end

        % Menu selected function: FileofParametersMenu
        function FileofParametersMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uigetfile(filterspec,'Dynablast 2.0 Program');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                openFileParameters(app,fullname);
            end
        end

        % Button pushed function: btn_RunButton_General
        function btn_RunButton_GeneralPushed(app, event)
            clc;            
            
            % Evaluate parameters
            [support, blast_equation, type_explosion, theory, negative, ...
                analysis_dynamic, ~, stress_strain, ~] = ...
                app.Components...
                (app.cmb_support.Value, ...
                app.cmb_blast_equation.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.cmb_structure_theory.Value, ...
                app.NegativePhaseSwitch.Value, ...
                app.DynamicSwitch.Value, ...
                app.cmb_case.Value, ...
                app.StressStrainSwitch.Value, ...
                app.TypeDistance.SelectedObject.Text);

            bool    = VerifyComponents(app, blast_equation);

            if (bool == false)
                return;
            end
            
            % Setting values to constructor method
            app.value_plate         = Class_Plate...
                (app.txt_a.Value, app.txt_b.Value, app.txt_c.Value, ...
                app.txt_shearfactor.Value, 1, 1, app.txt_xi.Value, ...
                app.txt_yi.Value, 0, support, 0);
            
            % Converting cell to array
            table                   = cell2mat(app.tbl_layers.Data);
            app.value_layer         = Class_Layer...
                (height(app.tbl_layers.Data), ...
                table(:,2), table(:,3), table(:,4), table(:,5), ...
                table(:,6), table(:,7), table(:,8), table(:,9), ...
                table(:,10), table(:,11));
            
            app.value_tnt           = Class_TNT...
                (app.txt_Z.Value, app.txt_W.Value, app.txt_R.Value, ...
                type_explosion, app.txt_pmax.Value, app.txt_pmin.Value, ...
                app.txt_td.Value, app.txt_id.Value, app.txt_im.Value, ...
                app.txt_decay.Value, blast_equation);          
            
            app.value_analysis      = Class_Analysis...
                (theory, analysis_dynamic, negative, ...
                app.txt_timeanalysis.Value, stress_strain, 0);
            
            app.value_advanalysis   = Class_AdvAnalysis(1, ...
                    app.txt_Z.Value, app.txt_W.Value, ...
                    app.txt_W_final.Value, app.txt_interval.Value, ...
                    negative);

            app.value_control       = Class_Control(app.value_plate, ...
                app.value_layer, app.value_tnt, ...
                app.value_analysis, app.value_advanalysis);
                     
            % Final results
            displacement = app.value_control.vt_result.fd;
            pressure     = app.value_control.vt_result.pressure;
            strain       = app.value_control.vt_strain.sol_strain;
            stress       = app.value_control.vt_stress.sol_stress;
            
            dim_pres = size(pressure,1);
            dim_disp = size(displacement,1);

            if dim_pres < dim_disp
                
                for i = dim_pres + 1 : dim_disp

                    pressure = cat(1, pressure, [displacement(i, 1), 0]);

                end

            end

            % Create a matrix for UITable
            S1           = cat(2,displacement(:,1), ...
                displacement(:,2), pressure(:,2));
            
            % Deleting the first column for concatenate with stress 
            % data base
            strain(:,1)  = [];
            stress(:,1)  = [];
            S2           = cat(2, stress, strain);
            
            % Open new window
            app.appgraph = App_Graph;
            
            % Completing Table Database
            app.appgraph.UITable.Data   = S1;
            app.appgraph.UITable2.Data  = S2;
            
            % Ploting graphics
            draw = Class_Draw;
            
            draw.plotGraphic(app.appgraph.UIAxes_Displacement, ...
                S1(:,1), S1(:,2), 't (s)', 'w / h');
            draw.plotGraphic(app.appgraph.UIAxes_Pressure, ...
                S1(:,1), S1(:,3), 't (s)', 'Pressure (Pa)');
                        
            draw.plotGraphic(app.appgraph.UIAxes_StressXX, ...
                 stress(:,1), S2(:,1), 'time (s)', '\sigma_x_x (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StressYY, ...
                 stress(:,1), S2(:,2), 'time (s)', '\sigma_y_y (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StressXY, ...
                 stress(:,1), S2(:,3), 'time (s)', '\sigma_x_y (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StressXZ, ...
                 stress(:,1), S2(:,4), 'time (s)', '\sigma_x_z (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StressYZ, ...
                 stress(:,1), S2(:,5), 'time (s)', '\sigma_y_z (MPa)');   
            
            draw.plotGraphic(app.appgraph.UIAxes_StrainXX, ...
                 strain(:,1), S2(:,6), 'time (s)', '\epsilon_x_x (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainYY, ...
                 strain(:,1), S2(:,7), 'time (s)', '\epsilon_y_y (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainXY, ...
                 strain(:,1), S2(:,8), 'time (s)', '\epsilon_x_y (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainXZ, ...
                 strain(:,1), S2(:,9), 'time (s)', '\epsilon_x_z (MPa)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainYZ, ...
                 strain(:,1), S2(:,10), 'time (s)', '\epsilon_y_z (MPa)');
            
        end

        % Button pushed function: btn_RunButton_Adv
        function btn_RunButton_AdvPushed(app, event)
            clc;            
                        
            % Evaluate parameters
            [support, blast_equation, type_explosion, theory, negative, ...
                analysis_dynamic, advanced_parameter, stress_strain, ~] = ...
                app.Components...
                (app.cmb_support.Value, ...
                app.cmb_blast_equation.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.cmb_structure_theory.Value, ...
                app.NegativePhaseSwitch.Value, ...
                app.DynamicSwitch.Value, ...
                app.cmb_case.Value, ...
                app.StressStrainSwitch.Value, ...
                app.TypeDistance.SelectedObject.Text);

            bool_components         = VerifyComponents(app, blast_equation);

            bool_adv                = VerifyAdvancedComponents...
                                      (app, advanced_parameter);

            % ----------------------------------------------------------- %

            if (bool_components == false || bool_adv == false)
                return;
            end
            
            % ----------------------------------------------------------- %

            % Setting values to constructor method
            app.value_plate         = Class_Plate...
                (app.txt_a.Value, app.txt_b.Value, app.txt_c.Value, ...
                app.txt_shearfactor.Value, 1, 1, app.txt_xi.Value, ...
                app.txt_yi.Value, 0, support, 0);
            
            % Converting cell to array
            table                   = cell2mat(app.tbl_layers.Data);
            app.value_layer         = Class_Layer...
                (height(app.tbl_layers.Data), ...
                table(:,2), table(:,3), table(:,4), table(:,5), ...
                table(:,6), table(:,7), table(:,8), table(:,9), ...
                table(:,10), table(:,11));
            
            app.value_tnt           = Class_TNT...
                (app.txt_Z.Value, app.txt_W.Value, app.txt_R.Value, ...
                type_explosion, app.txt_pmax.Value, app.txt_pmin.Value, ...
                app.txt_td.Value, app.txt_id.Value, app.txt_im.Value, ...
                app.txt_decay.Value, blast_equation);          
            
            app.value_analysis      = Class_Analysis...
                (theory, analysis_dynamic, negative, ...
                app.txt_timeanalysis.Value, stress_strain, 1);
            
            app.value_advanalysis   = Class_AdvAnalysis(advanced_parameter, ...
                    app.txt_Z.Value, app.txt_W.Value, ...
                    app.txt_W_final.Value, app.txt_interval.Value, ...
                    negative);

            app.value_control       = Class_Control(app.value_plate, ...
                app.value_layer, app.value_tnt, ...
                app.value_analysis, app.value_advanalysis);
                               
            % ----------------------------------------------------------- %

            % Final results
            matrix_adv      = app.value_control.vt_advresult.matrix_adv;
            vectorW         = app.value_control.vt_advresult.vectorW;
            vectorR         = app.value_control.vt_advresult.vectorR;
            
            % ----------------------------------------------------------- %

            % Open new window

            parameter = app.value_control.advanalysis.adv_parameter;

            if parameter ~= 9
                
                % Open new window
                app.appAdvanced = App_Advanced_Analysis;

                % Applicating the values in Table Database
                app.appAdvanced.UITable.Data = matrix_adv;

            end

            % ----------------------------------------------------------- %

            % General Equation coefficients

            if parameter == 8

                A               = app.value_control.vt_advresult.A;
                B               = app.value_control.vt_advresult.B;
                C               = app.value_control.vt_advresult.C;
                D               = app.value_control.vt_advresult.D;
                E               = app.value_control.vt_advresult.E;
                F               = app.value_control.vt_advresult.F;

            end

            % ----------------------------------------------------------- %

            % Verificating the parameter

            draw = Class_Draw;

            switch advanced_parameter

                case 1
                    app.appAdvanced.UITable.ColumnName = ...
                        {'Z m/kg^(1/3)', 'u_z / h'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        'Z (m / kg ^1 ^/ ^3)', 'u_z / h');
                case 2
                    app.appAdvanced.UITable.ColumnName = ...
                        {'W (kg)', 'u_z (without N.)', ...
                        'u_z (with N.)'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        'W _T _N _T (kg)', 'u_z');
                case 3
                    app.appAdvanced.UITable.ColumnName = ...
                        {'R (m)', 'u_z / h'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        'R (m)', 'u_z / h');
                case 4
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_N_L', 'DAF'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        't_d / T_N_L', 'DAF');
                case 5
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_L', 'DAF'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes,...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        't_d / T_L', 'DAF');
                case 6
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_L', 'DAF'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes,...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        't_d / T_L', 'DAF');
                case 7
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_N_L', 't_d + t_m / T_NL', 'T_NL', ...
                        'u_z / h'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        't_d / T_N_L', 'u_z / h');
                case 8

                    vec_column      = strings(size(vectorW,1),1);
                    table_column    = {'Z'}; 

                    for i = 1 : size(vectorW,1)
                        vec_column(i)   = "W = " + num2str(vectorW(i)) + " kg";  
                        table_column    = [table_column, vec_column(i)]; 
                    end

                    app.appAdvanced.UITable.ColumnName = table_column;

                    draw.plotGraphic4(app.appAdvanced.UIAxes, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        matrix_adv(:,3), matrix_adv(:,4),  matrix_adv(:,5), ...
                        'Z (m / kg ^1 ^/ ^3)', 'u_z / h', ...
                        vec_column(1), vec_column(2), ...
                        vec_column(3), vec_column(4));

                    % Save file General Equation
                    filterspec = {'*.txt'};
                    [filename,pathname] = uiputfile...
                        (filterspec,'Dynablast Program - General Equation');
                    if ~isequal(filename,0)
                        fullname = strcat(pathname,filename);
                        saveEquation(app,fullname, A, B, C, D, E, F)
                    end

                case 9
                    draw.plotGraphic3D(vectorW, vectorR, matrix_adv);

            end

        end

        % Menu selected function: AboutSoftwareMenu
        function AboutSoftwareMenuSelected(app, event)
            App_About;
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            App_Help;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DYNAblast20UIFigure = uifigure('Visible', 'off');
            app.DYNAblast20UIFigure.Name = 'DYNAblast2.0';
            app.FileMenu = uimenu(app.DYNAblast20UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.FileofLayersMenu = uimenu(app.OpenMenu);
            app.FileofLayersMenu.MenuSelectedFcn = createCallbackFcn(app, @FileofLayersMenuSelected, true);
            app.FileofLayersMenu.Text = 'File of Layers';
            app.FileofParametersMenu = uimenu(app.OpenMenu);
            app.FileofParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @FileofParametersMenuSelected, true);
            app.FileofParametersMenu.Text = 'File of Parameters';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.AboutMenu = uimenu(app.DYNAblast20UIFigure);
            app.AboutMenu.Text = 'About';
            app.HelpMenu = uimenu(app.AboutMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.AboutSoftwareMenu = uimenu(app.AboutMenu);
            app.AboutSoftwareMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSoftwareMenuSelected, true);
            app.AboutSoftwareMenu.Text = 'About Software';
            app.TabGroup = uitabgroup(app.DYNAblast20UIFigure);
            app.StructureTab = uitab(app.TabGroup);
            app.StructureTab.Title = 'Structure';
            app.txt_c = uieditfield(app.StructureTab, 'numeric');
            app.txt_c.Limits = [0 Inf];
            app.txt_c.ValueDisplayFormat = '%111.6g';
            app.DampingsCoefficientLabel = uilabel(app.StructureTab);
            app.DampingsCoefficientLabel.Text = 'Damping''s Coefficient';
            app.AnalysispointyLabel = uilabel(app.StructureTab);
            app.AnalysispointyLabel.Text = 'Analysis point y:';
            app.AnalysispointxLabel = uilabel(app.StructureTab);
            app.AnalysispointxLabel.Text = 'Analysis point x:';
            app.txt_yi = uieditfield(app.StructureTab, 'numeric');
            app.txt_yi.Limits = [0 Inf];
            app.txt_yi.ValueDisplayFormat = '%111.6g';
            app.txt_yi.Value = 0.11;
            app.txt_xi = uieditfield(app.StructureTab, 'numeric');
            app.txt_xi.Limits = [0 Inf];
            app.txt_xi.ValueDisplayFormat = '%111.6g';
            app.txt_xi.Value = 0.11;
            app.PointofAnalysisLabel = uilabel(app.StructureTab);
            app.PointofAnalysisLabel.Text = 'Point of Analysis';
            app.ShearFactorLabel = uilabel(app.StructureTab);
            app.ShearFactorLabel.Text = 'Shear Factor:';
            app.LengthatyaxismLabel = uilabel(app.StructureTab);
            app.LengthatyaxismLabel.Text = 'Length at y axis (m):';
            app.LengthatxaxismLabel = uilabel(app.StructureTab);
            app.LengthatxaxismLabel.Text = 'Length at x axis (m):';
            app.txt_shearfactor = uieditfield(app.StructureTab, 'numeric');
            app.txt_shearfactor.Limits = [0 Inf];
            app.txt_shearfactor.ValueDisplayFormat = '%111.6g';
            app.txt_shearfactor.Value = 1;
            app.txt_b = uieditfield(app.StructureTab, 'numeric');
            app.txt_b.Limits = [0.0001 Inf];
            app.txt_b.ValueDisplayFormat = '%111.6g';
            app.txt_b.Value = 0.22;
            app.txt_a = uieditfield(app.StructureTab, 'numeric');
            app.txt_a.Limits = [0.0001 Inf];
            app.txt_a.ValueDisplayFormat = '%111.6g';
            app.txt_a.Value = 0.22;
            app.GeometricCharacteristicsLabel = uilabel(app.StructureTab);
            app.GeometricCharacteristicsLabel.Text = 'Geometric Characteristics';
            app.cmb_support = uidropdown(app.StructureTab);
            app.cmb_support.Items = {'SS1', 'SS2', 'CCCC'};
            app.cmb_support.Value = 'SS1';
            app.SupportLabel = uilabel(app.StructureTab);
            app.SupportLabel.Text = 'Support:';
            app.BoundaryConditionsLabel = uilabel(app.StructureTab);
            app.BoundaryConditionsLabel.Text = 'Boundary Conditions';
            app.btn_minus = uibutton(app.StructureTab, 'push');
            app.btn_minus.ButtonPushedFcn = createCallbackFcn(app, @btn_minusButtonPushed, true);
            app.btn_minus.Icon = fullfile(pathToMLAPP, 'Image', 'minus_button.png');
            app.btn_minus.Text = '';
            app.btn_plus = uibutton(app.StructureTab, 'push');
            app.btn_plus.ButtonPushedFcn = createCallbackFcn(app, @btn_plusButtonPushed, true);
            app.btn_plus.Icon = fullfile(pathToMLAPP, 'Image', 'plus_button.png');
            app.btn_plus.Text = '';
            app.tbl_layers = uitable(app.StructureTab);
            app.tbl_layers.ColumnName = {'Layer'; 'Height'; 'Mass Density'; 'Angle (rad)'; 'nu12'; 'nu21'; 'E1'; 'E2'; 'G12'; 'G13'; 'G23'};
            app.tbl_layers.RowName = {};
            app.tbl_layers.ColumnEditable = [false true true true true true true true true true true];
            app.tbl_layers.CellEditCallback = createCallbackFcn(app, @tbl_layersCellEdit, true);
            app.Image = uiimage(app.StructureTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Image', 'Plate.png');
            app.Image2 = uiimage(app.StructureTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Image', 'Layers.png');
            app.btn_duplicate = uibutton(app.StructureTab, 'push');
            app.btn_duplicate.ButtonPushedFcn = createCallbackFcn(app, @btn_duplicateButtonPushed, true);
            app.btn_duplicate.Icon = fullfile(pathToMLAPP, 'Image', 'duplicate_button.png');
            app.btn_duplicate.Text = '';
            app.LoadingandAnalysisTab = uitab(app.TabGroup);
            app.LoadingandAnalysisTab.Title = 'Loading and Analysis';
            app.ExplosionLoadingPanel = uipanel(app.LoadingandAnalysisTab);
            app.ExplosionLoadingPanel.Title = 'Explosion Loading';
            app.cmb_blast_equation = uidropdown(app.ExplosionLoadingPanel);
            app.cmb_blast_equation.Items = {'Friedlander + Cubic Equation - Rigby Calibration', 'Friedlander + Cubic Equation (calculate tm) - Rigby Calibration', 'Friedlander + Cubic Equation (calculate tm) - Reis Calibration', 'Friedlander + Cubic Equation - Experimental Data', 'Expanded Friedlander - Rigby Calibration', 'Expanded Friedlander - Experimental Data'};
            app.cmb_blast_equation.ValueChangedFcn = createCallbackFcn(app, @cmb_blast_equationValueChanged, true);
            app.cmb_blast_equation.Value = 'Expanded Friedlander - Experimental Data';
            app.TypeofEquationLabel = uilabel(app.ExplosionLoadingPanel);
            app.TypeofEquationLabel.Text = 'Type of Equation';
            app.txt_im = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_im.Limits = [0 Inf];
            app.txt_im.ValueDisplayFormat = '%111.6g';
            app.txt_id = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_id.Limits = [0 Inf];
            app.txt_id.ValueDisplayFormat = '%111.6g';
            app.txt_td = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_td.Limits = [0 1];
            app.txt_td.ValueDisplayFormat = '%111.6g';
            app.txt_td.Value = 0.0018;
            app.txt_pmin = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_pmin.Limits = [0 Inf];
            app.txt_pmin.ValueDisplayFormat = '%111.6g';
            app.txt_pmax = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_pmax.Limits = [0 Inf];
            app.txt_pmax.ValueDisplayFormat = '%111.6g';
            app.txt_pmax.Value = 28900;
            app.txt_Z = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_Z.Limits = [5 37];
            app.txt_Z.ValueDisplayFormat = '%111.6g';
            app.txt_Z.ValueChangedFcn = createCallbackFcn(app, @txt_ZValueChanged, true);
            app.txt_Z.Value = 8.5393;
            app.txt_W = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_W.Limits = [0.0001 Inf];
            app.txt_W.ValueDisplayFormat = '%111.6g';
            app.txt_W.Value = 0.1088;
            app.NegativeImpulsePasLabel = uilabel(app.ExplosionLoadingPanel);
            app.NegativeImpulsePasLabel.Text = 'Negative Impulse (Pa.s):';
            app.PositiveImpulsePasLabel = uilabel(app.ExplosionLoadingPanel);
            app.PositiveImpulsePasLabel.Text = 'Positive Impulse (Pa.s):';
            app.PositivetimesLabel = uilabel(app.ExplosionLoadingPanel);
            app.PositivetimesLabel.Text = 'Positive time (s):';
            app.MaxUnderpressurePaLabel = uilabel(app.ExplosionLoadingPanel);
            app.MaxUnderpressurePaLabel.Text = 'Max Underpressure (Pa):';
            app.MaxSobrepressurePaLabel = uilabel(app.ExplosionLoadingPanel);
            app.MaxSobrepressurePaLabel.Text = 'Max Sobrepressure (Pa):';
            app.ScaledDistanceZkgm13Label = uilabel(app.ExplosionLoadingPanel);
            app.ScaledDistanceZkgm13Label.Text = 'Scaled Distance Z (kg/m^1/3):';
            app.TotalMasskgLabel = uilabel(app.ExplosionLoadingPanel);
            app.TotalMasskgLabel.Text = 'Total Mass (kg):';
            app.CharacteristicsLabel = uilabel(app.ExplosionLoadingPanel);
            app.CharacteristicsLabel.Text = 'Characteristics';
            app.TypeofExplosionLabel = uilabel(app.ExplosionLoadingPanel);
            app.TypeofExplosionLabel.Text = 'Type of Explosion';
            app.TypeExplosion = uibuttongroup(app.ExplosionLoadingPanel);
            app.SphericalButton = uiradiobutton(app.TypeExplosion);
            app.SphericalButton.Text = 'Spherical';
            app.HemisphericalButton = uiradiobutton(app.TypeExplosion);
            app.HemisphericalButton.Text = 'Hemispherical';
            app.HemisphericalButton.Value = true;
            app.DecayCoefficientLabel = uilabel(app.ExplosionLoadingPanel);
            app.DecayCoefficientLabel.Text = 'Decay Coefficient:';
            app.txt_decay = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_decay.Limits = [0 Inf];
            app.txt_decay.ValueDisplayFormat = '%111.6g';
            app.txt_decay.Value = 0.35;
            app.DistanceRmLabel = uilabel(app.ExplosionLoadingPanel);
            app.DistanceRmLabel.Text = 'Distance R (m):';
            app.txt_R = uieditfield(app.ExplosionLoadingPanel, 'numeric');
            app.txt_R.Limits = [0.0001 38];
            app.txt_R.ValueDisplayFormat = '%111.6g';
            app.txt_R.ValueChangedFcn = createCallbackFcn(app, @txt_RValueChanged, true);
            app.txt_R.Value = 4.0766;
            app.TypeofDistanceLabel = uilabel(app.ExplosionLoadingPanel);
            app.TypeofDistanceLabel.Text = 'Type of Distance';
            app.TypeDistance = uibuttongroup(app.ExplosionLoadingPanel);
            app.TypeDistance.SelectionChangedFcn = createCallbackFcn(app, @TypeDistanceSelectionChanged, true);
            app.DistanceRButton = uiradiobutton(app.TypeDistance);
            app.DistanceRButton.Text = 'Distance R';
            app.ScaledDistanceZButton = uiradiobutton(app.TypeDistance);
            app.ScaledDistanceZButton.Text = 'Scaled Distance Z';
            app.ScaledDistanceZButton.Value = true;
            app.AnalysisPanel = uipanel(app.LoadingandAnalysisTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.timesLabel = uilabel(app.AnalysisPanel);
            app.timesLabel.Text = 'time (s):';
            app.txt_timeanalysis = uieditfield(app.AnalysisPanel, 'numeric');
            app.txt_timeanalysis.Limits = [0 Inf];
            app.txt_timeanalysis.ValueDisplayFormat = '%111.6g';
            app.txt_timeanalysis.Value = 0.02;
            app.TimeofAnalysisLabel = uilabel(app.AnalysisPanel);
            app.TimeofAnalysisLabel.Text = 'Time of Analysis';
            app.DynamicAnalysisLabel = uilabel(app.AnalysisPanel);
            app.DynamicAnalysisLabel.Text = 'Dynamic Analysis';
            app.NegativePhaseLabel = uilabel(app.AnalysisPanel);
            app.NegativePhaseLabel.Text = 'Negative Phase';
            app.cmb_structure_theory = uidropdown(app.AnalysisPanel);
            app.cmb_structure_theory.Items = {'CLPT', 'FSPT', 'HSPT', 'TvK'};
            app.cmb_structure_theory.Value = 'TvK';
            app.TypeofAnalysisLabel = uilabel(app.AnalysisPanel);
            app.TypeofAnalysisLabel.Text = 'Type of Analysis';
            app.btn_RunButton_General = uibutton(app.AnalysisPanel, 'push');
            app.btn_RunButton_General.ButtonPushedFcn = createCallbackFcn(app, @btn_RunButton_GeneralPushed, true);
            app.btn_RunButton_General.Text = 'Dynamic Analysis';
            app.NegativePhaseSwitch = uiswitch(app.AnalysisPanel, 'slider');
            app.NegativePhaseSwitch.Value = 'On';
            app.DynamicSwitch = uiswitch(app.AnalysisPanel, 'slider');
            app.DynamicSwitch.Value = 'On';
            app.Image3 = uiimage(app.AnalysisPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Image', 'Line.png');
            app.txt_W_final = uieditfield(app.AnalysisPanel, 'numeric');
            app.txt_W_final.Limits = [0 Inf];
            app.txt_W_final.ValueDisplayFormat = '%111.6g';
            app.txt_interval = uieditfield(app.AnalysisPanel, 'numeric');
            app.txt_interval.Limits = [0 Inf];
            app.txt_interval.ValueDisplayFormat = '%111.6g';
            app.txt_interval_case2 = uilabel(app.AnalysisPanel);
            app.txt_interval_case2.Text = 'Number of intervals:';
            app.txt_mass_case2 = uilabel(app.AnalysisPanel);
            app.txt_mass_case2.Text = 'Final TNT''s mass (kg):';
            app.cmb_case = uidropdown(app.AnalysisPanel);
            app.cmb_case.Items = {'Case 1 - Z x uz / h', 'Case 2 - W x uz / h', 'Case 3 - R x uz / h', 'Case 4 - td / TNL x FAD - Varying W', 'Case 5 - td / TNL x FAD - Varying Z', 'Case 6 - td / TNL x FAD - Varying R', 'Case 7 - td / TNL x uz / h', 'Case 8 - General Equation', 'Case 9 - 3D Graphic - Surface'};
            app.cmb_case.ValueChangedFcn = createCallbackFcn(app, @cmb_caseValueChanged, true);
            app.cmb_case.Value = 'Case 1 - Z x uz / h';
            app.CaseofAnalysisLabel = uilabel(app.AnalysisPanel);
            app.CaseofAnalysisLabel.Text = 'Case of Analysis';
            app.btn_RunButton_Adv = uibutton(app.AnalysisPanel, 'push');
            app.btn_RunButton_Adv.ButtonPushedFcn = createCallbackFcn(app, @btn_RunButton_AdvPushed, true);
            app.btn_RunButton_Adv.Text = 'Advanced Analysis';
            app.MassofTNTLabel = uilabel(app.AnalysisPanel);
            app.MassofTNTLabel.Text = 'Mass of TNT';
            app.AnalysisofStressandStrainLabel = uilabel(app.AnalysisPanel);
            app.AnalysisofStressandStrainLabel.Text = 'Analysis of Stress and Strain';
            app.StressStrainSwitch = uiswitch(app.AnalysisPanel, 'slider');
            % Show the figure after all components are created
            app.DYNAblast20UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DYNAblast20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DYNAblast20UIFigure)
        end
    end
end