classdef App_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutSoftwareMenu              matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        PlateTab                       matlab.ui.container.Tab
        BoundaryConditionsLabel        matlab.ui.control.Label
        cmb_supportplate_x0            matlab.ui.control.DropDown
        cmb_supportplate_xa            matlab.ui.control.DropDown
        cmb_supportplate_y0            matlab.ui.control.DropDown
        cmb_supportplate_yb            matlab.ui.control.DropDown
        SupportLabel                   matlab.ui.control.Label
        x0Label                        matlab.ui.control.Label
        xaLabel                        matlab.ui.control.Label
        y0Label                        matlab.ui.control.Label
        ybLabel                        matlab.ui.control.Label
        cmb_support                    matlab.ui.control.DropDown
        GeometricCharacteristicsLabel  matlab.ui.control.Label
        txt_a                          matlab.ui.control.NumericEditField
        txt_b                          matlab.ui.control.NumericEditField
        txt_shearfactor                matlab.ui.control.NumericEditField
        LengthatxaxismLabel            matlab.ui.control.Label
        LengthatyaxismLabel            matlab.ui.control.Label
        ShearFactorLabel               matlab.ui.control.Label
        PointofAnalysisLabel           matlab.ui.control.Label
        txt_xi                         matlab.ui.control.NumericEditField
        txt_yi                         matlab.ui.control.NumericEditField
        AnalysispointxLabel            matlab.ui.control.Label
        AnalysispointyLabel            matlab.ui.control.Label
        DampingsCoefficientLabel       matlab.ui.control.Label
        txt_c                          matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        LayerTab                       matlab.ui.container.Tab
        tbl_layers                     matlab.ui.control.Table
        btn_plus                       matlab.ui.control.Button
        btn_minus                      matlab.ui.control.Button
        TNTTab                         matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        TypeExplosion                  matlab.ui.container.ButtonGroup
        HemisphericalButton            matlab.ui.control.RadioButton
        SphericalButton                matlab.ui.control.RadioButton
        TypeofExplosionLabel           matlab.ui.control.Label
        TypeofEquationLabel            matlab.ui.control.Label
        cmb_blast_equation             matlab.ui.control.DropDown
        CharacteristicsLabel           matlab.ui.control.Label
        TotalMasskgLabel               matlab.ui.control.Label
        ScalarDistanceZkgm13Label      matlab.ui.control.Label
        MaxSobrepressurePaLabel        matlab.ui.control.Label
        MaxUnderpressurePaLabel        matlab.ui.control.Label
        PositivetimesLabel             matlab.ui.control.Label
        PositiveImpulsePasLabel        matlab.ui.control.Label
        NegativeImpulsePasLabel        matlab.ui.control.Label
        txt_W                          matlab.ui.control.NumericEditField
        txt_Z                          matlab.ui.control.NumericEditField
        txt_pmax                       matlab.ui.control.NumericEditField
        txt_pmin                       matlab.ui.control.NumericEditField
        txt_td                         matlab.ui.control.NumericEditField
        txt_id                         matlab.ui.control.NumericEditField
        txt_im                         matlab.ui.control.NumericEditField
        AnalysisTab                    matlab.ui.container.Tab
        AdvancedAnalysisPanel          matlab.ui.container.Panel
        RunButton_Adv                  matlab.ui.control.Button
        CaseofAnalysisLabel            matlab.ui.control.Label
        cmb_case                       matlab.ui.control.DropDown
        txt_mass_case2                 matlab.ui.control.Label
        txt_interval_case2             matlab.ui.control.Label
        txt_interval                   matlab.ui.control.NumericEditField
        txt_W_final                    matlab.ui.control.NumericEditField
        mass_interval                  matlab.ui.container.ButtonGroup
        W100to1000kgButton             matlab.ui.control.RadioButton
        W1to10kgButton                 matlab.ui.control.RadioButton
        GeneralAnalysisPanel           matlab.ui.container.Panel
        btn_RunButton_General          matlab.ui.control.Button
        TypeofAnalysisLabel_2          matlab.ui.control.Label
        cmb_type_analysis              matlab.ui.control.DropDown
        NegativePhaseLabel             matlab.ui.control.Label
        NegativePhase                  matlab.ui.container.ButtonGroup
        NoButton                       matlab.ui.control.RadioButton
        YesButton                      matlab.ui.control.RadioButton
        DynamicAnalysisLabel_2         matlab.ui.control.Label
        cmb_dynamic                    matlab.ui.control.DropDown
        TimeofAnalysisLabel            matlab.ui.control.Label
        txt_timeanalysis               matlab.ui.control.NumericEditField
        timesLabel                     matlab.ui.control.Label
    end

    
    % General Properties
    
    properties (Access = public)
        
        control         Class_Control
        
        plate           Class_Plate
        layer           Class_Layer
        tnt             Class_TNT
        analysis        Class_Analysis
        adv_analysis    Class_AdvAnalysis
        pmt             Class_Parameters
        blast           Class_Blast
        effort          Class_Effort
        energy          Class_Energy
        solution        Class_SolutionNavier
        soldynamic      Class_SolutionNavierDynamic
        
        % Vectors for Class_Control
        value_control       = [];
        
        % Vectors for constructor solution
        value_plate         = [];
        value_layer         = [];
        value_tnt           = [];
        value_analysis      = [];
        value_advanalysis   = [];
        value_pmt           = [];
        
        % Vectors for solution
        value_effort        = [];
        value_energy        = [];
        value_solution      = [];
        value_result        = [];
        value_resultadv     = [];
        
        % Parameters to pass informations between windows
        appgraph
        appAdvanced
        
    end
    
    methods (Access = public)
        
        % Function to verificate the values of user's options
        function [x0, xa, y0, yb, support, blast_equation, ...
                type_explosion, theory, negative, ...
                analysis_dynamic, adv_analysis] = ...
                Avaliating(~, ap1, ap2, ap3, ap4, ap5, ap6, ap7, ...
                ap8, ap9, ap10, ap11)
            
            % Avaliating which type of boundary condition the user 
            % chose
            switch ap1
                case 'Simple Support'
                    x0 = 1;
                case 'Campled'
                    x0 = 2;
                case 'Free'
                    x0 = 3;
                    
            end
            
            switch ap2
                case 'Simple Support'
                    xa = 1;
                case 'Campled'
                    xa = 2;
                case 'Free'
                    xa = 3;
                    
            end
            
            switch ap3
                case 'Simple Support'
                    y0 = 1;
                case 'Campled'
                    y0 = 2;
                case 'Free'
                    y0 = 3;
                    
            end
            
            switch ap4
                case 'Simple Support'
                    yb = 1;
                case 'Campled'
                    yb = 2;
                case 'Free'
                    yb = 3;
                    
            end
            
            % Avaliating the type of support (if the user choose 
            % simple support
            switch ap5
                case 'None'
                    support = 0;
                case 'SS1'
                    support = 1;
                case 'SS2'
                    support = 2;
            end
            
            % Avaliating blast wave data base
            switch ap6
                case 'Friedlander + Cubic Equation - Rigby Calibration'
                    blast_equation = 1;
                case 'Friedlander + Cubic Equation (calculate tm) - Rigby Calibration'
                    blast_equation = 2;
                case 'Friedlander + Cubic Equation (calculate tm) - Ana Calibration'
                    blast_equation = 3;
                case 'Friedlander + Cubic Equation - Experimental Data'
                    blast_equation = 4;
                case 'Expanded Friedlander - Rigby Calibration'
                    blast_equation = 5;
                case 'Expanded Friedlander - Experimental Data'
                    blast_equation = 6;
            end
            
            % Avaliating the type of explosion
            switch ap7
                case 'Hemispherical'
                    type_explosion = 1;
                case 'Spherical'
                    type_explosion = 2;
            end
            
            
            % Avaliating which type of analysis the user chose
            switch ap8
                case 'CLPT'
                    theory = 1;
                case 'FSPT'
                    theory = 2;
                case 'TSPT'
                    theory = 3;
                case 'TvK'
                    theory = 4;
                case 'TSPT - FD'
                    theory = 5;
            end
            
            % Avaliating the consideration of negative phase
            switch ap9
                case 'Yes'
                    negative = 1;
                case 'No'
                    negative = 0;
            end            
            
            % Avaliating if dynamic analysis is on or off
            switch ap10
                case 'Off'
                    analysis_dynamic = 0;
                case 'On'
                    analysis_dynamic = 1;
            end
            
            % Avaliating the advanced analysis
            switch ap11
                case 'Case 1 - Z x uz / h'
                    adv_analysis = 1;
                case 'Case 2 - W x uz / h'
                    adv_analysis = 2;
                case 'Case 3 - td / TL x FAD - Variating W'
                    adv_analysis = 3;
                case 'Case 4 - td / TL x uz / h'
                    adv_analysis = 4;
                case 'Case 5 - td / TNL x uz / h'
                    adv_analysis = 5;
                case 'Case 6 - uz / h x stress'
                    adv_analysis = 6;
                case 'Case 7 - td / TL x FAD - Variating Z'
                    adv_analysis = 7;
                case 'Case 8 - General Equation'
                    adv_analysis = 8;
            end
            
        end
        
        % ---------------------------------------------------------- %
        
        % Function to create save file
        function saveFile(app,filename)
            
            % Colecting values
            [x0, xa, y0, yb, support, ~, ...
                ~, ~, negative, ...
                analysis_dynamic, ~] = ...
                app.Avaliating(...
                app.cmb_supportplate_x0.Value, ...
                app.cmb_supportplate_xa.Value, ...
                app.cmb_supportplate_y0.Value, ...
                app.cmb_supportplate_yb.Value, ...
                app.cmb_support.Value, app.cmb_blast_equation.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.cmb_type_analysis.Value, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.cmb_dynamic.Value, app.cmb_case.Value);
            
            % Open file with given file name for writing
            fid = fopen(filename,'wt+');
            
            % Writing boundary conditions
            fprintf(fid,'*Boundary Conditios* \n');
            fprintf(fid, 'x0 = %d \n', x0);
            fprintf(fid, 'xa = %d \n', xa);
            fprintf(fid, 'y0 = %d \n', y0);
            fprintf(fid, 'yb = %d \n', yb);
            fprintf(fid, 'Type of support: %d\n', support);
            fprintf(fid,'\n');
            
            % Geometric Characteristics
            fprintf(fid,'*Geometric Characteristics* \n');
            fprintf(fid, 'a = %2.1f \n', app.txt_a.Value);
            fprintf(fid, 'b = %2.1f \n', app.txt_b.Value);
            fprintf(fid, 'shear factor = %2.1f \n', app.txt_shearfactor);
            fprintf(fid,'\n');
            
            % Point of Analysis
            fprintf(fid,'*Point of Analysis* \n');
            fprintf(fid, 'xi = %2.1f \n', app.txt_xi.Value);
            fprintf(fid, 'yi = %2.1f \n', app.txt_yi.Value);
            fprintf(fid,'\n');
            
            % Damping Coefficient
            fprintf(fid,'*Damping Coefficient* \n');
            fprintf(fid, 'xi = %2.1f \n', app.txt_xi.Value);
            fprintf(fid,'\n');
            
            % TNT
            fprintf(fid,'*TNT* \n');
            fprintf(fid, 'Type of Explosion = %s\n', ...
                app.TypeExplosion.SelectedObject.Text);
            fprintf(fid, 'Equation = %s\n', ...
                app.cmb_blast_equation.Value);
            fprintf(fid, 'W = %2.10f\n', app.txt_W.Value);
            fprintf(fid, 'Z = %2.10f\n', app.txt_Z.Value);
            fprintf(fid, 'pmax = %2.10f\n', app.txt_pmax.Value);
            fprintf(fid, 'pmin = %2.10f\n', app.txt_pmin.Value);
            fprintf(fid, 'td = %2.10f\n', app.txt_td.Value);
            fprintf(fid, 'id = %2.10f\n', app.txt_id.Value);
            fprintf(fid, 'im = %2.10f\n', app.txt_im.Value);
            fprintf(fid,'\n');
            
            % Analysis
            fprintf(fid,'*General Analysis* \n');
            fprintf(fid, 'Theory = %s\n', ...
                app.cmb_type_analysis.Value);
            fprintf(fid, 'Negative Phase = %d\n', negative);
            fprintf(fid, 'Dynamic = %d\n', analysis_dynamic);
            fprintf(fid, 'Time Analysis = %2.10f\n', ...
                app.txt_timeanalysis.Value);
            fprintf(fid,'\n');
            
            fprintf(fid,'*Advanced Analysis* \n');
            fprintf(fid, 'Case = %s\n', app.cmb_case.Value);
            fprintf(fid, 'Final TNT Mass = %2.10f\n', ...
                app.txt_W_final.Value);
            fprintf(fid, 'Number of intervals = %d\n', ...
                app.txt_interval.Value);
            fprintf(fid, 'Mass Interval = %s\n', ...
                app.mass_interval.SelectedObject.Text);
                        
            fclose(fid);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: tbl_layers
        function tbl_layersCellEdit(app, event)
            app.tbl_layers.ColumnEditable = [false, true, true, true, ...
                true, true, true, true, true, true, true];
            app.tbl_layers.ColumnFormat = {'numeric' 'numeric' 'numeric' ...
                'numeric' 'numeric' 'numeric' 'numeric' 'numeric'...
                 'numeric' 'numeric'};
            app.tbl_layers.RowName = 'numbered'; 
        end

        % Button pushed function: btn_plus
        function btn_plusButtonPushed(app, event)
            % First is necessary to verificate if the table is empty
            verification = isempty(app.tbl_layers.Data);
            
            % Creating a new array that is based on the number of rows
            if verification == true
                rowdata = {1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0};
            else
                row     = height(app.tbl_layers.Data);
                rowdata = {row + 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0};
            end
            
            % Update table data
            app.tbl_layers.Data = [app.tbl_layers.Data; rowdata];
        end

        % Button pushed function: btn_minus
        function btn_minusButtonPushed(app, event)
            % First is necessary to verificate if the table is empty
            verification = isempty(app.tbl_layers.Data);
            
            % Creating a new array that is based on the number of rows
            if verification == true
                msgbox('The table is empty!','Informatio','warn');
            else
                row     = height(app.tbl_layers.Data);
                app.tbl_layers.Data(row,:) = [];
            end
            
        end

        % Button pushed function: btn_RunButton_General
        function btn_RunButton_GeneralPushed(app, event)
            % Avaliating parameters
            [x0, xa, y0, yb, ~, blast_equation, ...
                type_explosion, theory, negative, ...
                analysis_dynamic, ~] = ...
                app.Avaliating(...
                app.cmb_supportplate_x0.Value, ...
                app.cmb_supportplate_xa.Value, ...
                app.cmb_supportplate_y0.Value, ...
                app.cmb_supportplate_yb.Value, ...
                app.cmb_support.Value, app.cmb_blast_equation.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.cmb_type_analysis.Value, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.cmb_dynamic.Value, app.cmb_case.Value);
            
            % Sending values to constructor method
            app.value_plate         = Class_Plate...
                (app.txt_a.Value, app.txt_b.Value, app.txt_c.Value, ...
                app.txt_shearfactor.Value, app.txt_shearfactor.Value, ...
                1, 1, app.txt_xi.Value, app.txt_yi.Value, ...
                x0, xa, y0, yb);
            
            % Converting cell to array
            table                   = cell2mat(app.tbl_layers.Data);
            app.value_layer         = Class_Layer...
                (height(app.tbl_layers.Data), ...
                table(:,2), table(:,3), table(:,4), table(:,5), ...
                table(:,6), table(:,7), table(:,8), table(:,9), ...
                table(:,10), table(:,11));
            
            app.value_tnt           = Class_TNT...
                (app.txt_Z.Value, app.txt_W.Value, type_explosion, ...
                app.txt_pmax.Value, app.txt_pmin.Value, app.txt_td.Value,...
                app.txt_id.Value, app.txt_im.Value, blast_equation);          
            
            app.value_analysis      = Class_Analysis...
                (theory, analysis_dynamic, negative, ...
                app.txt_timeanalysis.Value, 0);
            
            app.value_advanalysis   = Class_AdvAnalysis(1, 1, ...
                    app.txt_Z.Value, app.txt_W.Value, ...
                    app.txt_W_final.Value, app.txt_interval.Value, negative);
            
            app.value_control       = Class_Control(app.value_plate, ...
                app.value_layer, app.value_tnt, ...
                app.value_analysis, app.value_advanalysis);
                     
            % Final results
            displacement = app.value_control.vt_result.disp_time;
            pressure     = app.value_control.vt_result.pressure;
            stress       = app.value_control.vt_result.sol_stress;
            strain       = app.value_control.vt_result.sol_strain;
            
            % Create a matrix for UITable
            S1           = cat(2,displacement(:,1), ...
                displacement(:,4), pressure(:,2));
            
            % Deleting the first column for concatenate with stress 
            % data base
            strain(:,1)  = [];
            S2           = cat(2, stress, strain);
            
            % Open new window
            app.appgraph = App_Graph;
            
            % Completing Table Database
            app.appgraph.UITable.Data   = S1;
            app.appgraph.UITable_2.Data = S2;
            
            % Ploting graphics
            draw = Class_Draw;
            
            draw.plotGraphic(app.appgraph.UIAxes_Displacement, ...
                S1(:,1), S1(:,2), 't (s)', 'w / h');
            draw.plotGraphic(app.appgraph.UIAxes_Pressure, ...
                S1(:,1), S1(:,3), 't (s)', 'Pressure (Pa)');
            
            
            draw.plotGraphic(app.appgraph.UIAxes_StressXX, ...
                S2(:,2), S2(:,1), '\sigma_x_x (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StressYY, ...
                S1(:,3), S1(:,1), '\sigma_y_y (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StressXY, ...
                S1(:,4), S1(:,1), '\sigma_x_y (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StressXZ, ...
                S1(:,5), S1(:,1), '\sigma_x_z (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StressYZ, ...
                S1(:,6), S1(:,1), '\sigma_y_z (MPa)', 'z_i (m)');   
            
            draw.plotGraphic(app.appgraph.UIAxes_StrainXX, ...
                S2(:,7), S2(:,1), '\epsilon_x_x (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainYY, ...
                S1(:,8), S1(:,1), '\epsilon_y_y (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainXY, ...
                S1(:,9), S1(:,1), '\epsilon_x_y (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainXZ, ...
                S1(:,10), S1(:,1), '\epsilon_x_z (MPa)', 'z_i (m)');
            draw.plotGraphic(app.appgraph.UIAxes_StrainYZ, ...
                S1(:,11), S1(:,1), '\epsilon_y_z (MPa)', 'z_i (m)');
            
        end

        % Button pushed function: RunButton_Adv
        function RunButton_AdvPushed(app, event)
            % Avaliating parameters
            [x0, xa, y0, yb, ~, blast_equation, ...
                type_explosion, theory, negative, ...
                analysis_dynamic, adv] = ...
                app.Avaliating(...
                app.cmb_supportplate_x0.Value, ...
                app.cmb_supportplate_xa.Value, ...
                app.cmb_supportplate_y0.Value, ...
                app.cmb_supportplate_yb.Value, ...
                app.cmb_support.Value, app.cmb_blast_equation.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.cmb_type_analysis.Value, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.cmb_dynamic.Value, app.cmb_case.Value);
            
            % Sending values to constructor method
            app.value_plate         = Class_Plate...
                (app.txt_a.Value, app.txt_b.Value, app.txt_c.Value, ...
                app.txt_shearfactor.Value, app.txt_shearfactor.Value, ...
                1, 1, app.txt_xi.Value, app.txt_yi.Value, ...
                x0, xa, y0, yb);
            
            % Converting cell to array
            table                   = cell2mat(app.tbl_layers.Data);
            app.value_layer         = Class_Layer...
                (height(app.tbl_layers.Data), ...
                table(:,2), table(:,3), table(:,4), table(:,5), ...
                table(:,6), table(:,7), table(:,8), table(:,9), ...
                table(:,10), table(:,11));
            
            app.value_tnt           = Class_TNT...
                (app.txt_Z.Value, app.txt_W.Value, type_explosion, ...
                app.txt_pmax.Value, app.txt_pmin.Value, app.txt_td.Value,...
                app.txt_id.Value, app.txt_im.Value, blast_equation);          
            
            app.value_analysis      = Class_Analysis...
                (theory, analysis_dynamic, negative, ...
                app.txt_timeanalysis.Value, 0);
            
            app.value_advanalysis   = Class_AdvAnalysis...
                (adv, app.txt_Z.Value, app.txt_W.Value, ...
                    app.txt_W_final.Value, app.txt_interval.Value, negative);
            
            app.value_control       = Class_Control(app.value_plate, ...
                app.value_layer, app.value_tnt, ...
                app.value_analysis, app.value_advanalysis);
            
            % Final results
            matrix_adv = app.value_control.vt_advresult.matrix_adv;
            
            % Open new window
            app.appAdvanced = App_Advanced_Analysis;

            % Applicating the values in Table Database
            app.appAdvanced.UITable.Data = matrix_adv;

            % Verificating the parameter
            draw = Class_Plot;

            switch adv

                case 1
                    app.appAdvanced.UITable.ColumnName = ...
                        {'Z m/kg^(1/3)', 'u_z / h'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        'Z (m / kg ^1 ^/ ^3)', 'u_z / h');

                case 2
                    app.appAdvanced.UITable.ColumnName = ...
                        {'W (kg)', 'u_z (without N.)', ...
                        'u_z (with N.)'};

                    draw.plotGraphic2...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        matrix_adv(:,3),'W _T _N _T (kg)', 'u_z', ...
                        ' without Negative Phase', ' with Negative Phase');

                case 3
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_L', 'DAF'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        't_d / T_L', 'DAF');

                case 4
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_L', '(t_d + t_m) / T_L', 'u_z / h'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,1), matrix_adv(:,3), ...
                        't_d / T_L', 'u_z / h');

                case 5
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_NL', 't_d + tm / T_NL', 'T_NL', 'u_z / h'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,1), matrix_adv(:,4), ...
                        't_d / T_NL', 'u_z / h');

                case 6
                    app.appAdvanced.UITable.ColumnName = ...
                        {'Z', '\sigma_x_x', '\sigma_y_y', 'u_z / h'};

                    draw.plotGraphic2...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,4), matrix_adv(:,2), ...
                        matrix_adv(:,3),'u_z / h', ...
                        '\sigma_m / (\sigma_m + \sigma_b)', ...
                        ' \sigma_x_x', ' \sigma_y_y');

                case 7
                    app.appAdvanced.UITable.ColumnName = ...
                        {'t_d / T_L', 'DAF'};

                    draw.plotGraphic...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,1), matrix_adv(:,2),'t_d / T_L', 'DAF');

                case 8
                    app.appAdvanced.UITable.ColumnName = {'Z', 'W = 1 kg', ...
                        'W = 2 kg', 'W = 3 kg', 'W = 4 kg', 'W = 5 kg', ...
                        'W = 6 kg', 'W = 7 kg', 'W = 8 kg', 'W = 9 kg', ...
                        'W = 10 kg'};

                    draw.plotGraphic4...
                        (app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                        matrix_adv(:,1), matrix_adv(:,2), ...
                        matrix_adv(:,3), matrix_adv(:,6), matrix_adv(:,11), ...
                        'Z (m / kg ^1 ^/ ^3)', 'u_z / h', ' - W = 1 kg', ' - W = 2 kg', ...
                        ' - W = 5 kg', ' - W = 10 kg');

            end

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uigetfile(filterspec,'Dynablast Program');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                openFile(app,fullname);
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uiputfile(filterspec,'Dynablast Program');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                saveFile(app,fullname)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.HelpMenu = uimenu(app.AboutMenu);
            app.HelpMenu.Text = 'Help';
            app.AboutSoftwareMenu = uimenu(app.AboutMenu);
            app.AboutSoftwareMenu.Text = 'About Software';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlateTab = uitab(app.TabGroup);
            app.PlateTab.Title = 'Plate';
            app.UIAxes = uiaxes(app.PlateTab);
            zlabel(app.UIAxes, 'Z')
            app.txt_c = uieditfield(app.PlateTab, 'numeric');
            app.txt_c.Limits = [0 Inf];
            app.DampingsCoefficientLabel = uilabel(app.PlateTab);
            app.DampingsCoefficientLabel.Text = 'Damping''s Coefficient';
            app.AnalysispointyLabel = uilabel(app.PlateTab);
            app.AnalysispointyLabel.Text = 'Analysis point y:';
            app.AnalysispointxLabel = uilabel(app.PlateTab);
            app.AnalysispointxLabel.Text = 'Analysis point x:';
            app.txt_yi = uieditfield(app.PlateTab, 'numeric');
            app.txt_yi.Limits = [0 Inf];
            app.txt_yi.Value = 0.11;
            app.txt_xi = uieditfield(app.PlateTab, 'numeric');
            app.txt_xi.Limits = [0 Inf];
            app.txt_xi.Value = 0.11;
            app.PointofAnalysisLabel = uilabel(app.PlateTab);
            app.PointofAnalysisLabel.Text = 'Point of Analysis';
            app.ShearFactorLabel = uilabel(app.PlateTab);
            app.ShearFactorLabel.Text = 'Shear Factor:';
            app.LengthatyaxismLabel = uilabel(app.PlateTab);
            app.LengthatyaxismLabel.Text = 'Length at y axis (m):';
            app.LengthatxaxismLabel = uilabel(app.PlateTab);
            app.LengthatxaxismLabel.Text = 'Length at x axis (m):';
            app.txt_shearfactor = uieditfield(app.PlateTab, 'numeric');
            app.txt_shearfactor.Limits = [0 Inf];
            app.txt_shearfactor.Value = 1;
            app.txt_b = uieditfield(app.PlateTab, 'numeric');
            app.txt_b.Limits = [0.0001 Inf];
            app.txt_b.Value = 0.22;
            app.txt_a = uieditfield(app.PlateTab, 'numeric');
            app.txt_a.Limits = [0.0001 Inf];
            app.txt_a.Value = 0.22;
            app.GeometricCharacteristicsLabel = uilabel(app.PlateTab);
            app.GeometricCharacteristicsLabel.Text = 'Geometric Characteristics';
            app.cmb_support = uidropdown(app.PlateTab);
            app.cmb_support.Items = {'None', 'SS1', 'SS2'};
            app.cmb_support.Value = 'SS1';
            app.ybLabel = uilabel(app.PlateTab);
            app.ybLabel.Text = 'y = b';
            app.y0Label = uilabel(app.PlateTab);
            app.y0Label.Text = 'y = 0';
            app.xaLabel = uilabel(app.PlateTab);
            app.xaLabel.Text = 'x = a';
            app.x0Label = uilabel(app.PlateTab);
            app.x0Label.Text = 'x = 0';
            app.SupportLabel = uilabel(app.PlateTab);
            app.SupportLabel.Text = 'Support:';
            app.cmb_supportplate_yb = uidropdown(app.PlateTab);
            app.cmb_supportplate_yb.Items = {'Simple Support', 'Campled', 'Free'};
            app.cmb_supportplate_yb.Value = 'Simple Support';
            app.cmb_supportplate_y0 = uidropdown(app.PlateTab);
            app.cmb_supportplate_y0.Items = {'Simple Support', 'Campled', 'Free'};
            app.cmb_supportplate_y0.Value = 'Simple Support';
            app.cmb_supportplate_xa = uidropdown(app.PlateTab);
            app.cmb_supportplate_xa.Items = {'Simple Support', 'Campled', 'Free'};
            app.cmb_supportplate_xa.Value = 'Simple Support';
            app.cmb_supportplate_x0 = uidropdown(app.PlateTab);
            app.cmb_supportplate_x0.Items = {'Simple Support', 'Campled', 'Free'};
            app.cmb_supportplate_x0.Value = 'Simple Support';
            app.BoundaryConditionsLabel = uilabel(app.PlateTab);
            app.BoundaryConditionsLabel.Text = 'Boundary Conditions';
            app.LayerTab = uitab(app.TabGroup);
            app.LayerTab.Title = 'Layer';
            app.btn_minus = uibutton(app.LayerTab, 'push');
            app.btn_minus.ButtonPushedFcn = createCallbackFcn(app, @btn_minusButtonPushed, true);
            app.btn_minus.Text = '-';
            app.btn_plus = uibutton(app.LayerTab, 'push');
            app.btn_plus.ButtonPushedFcn = createCallbackFcn(app, @btn_plusButtonPushed, true);
            app.btn_plus.Text = '+';
            app.tbl_layers = uitable(app.LayerTab);
            app.tbl_layers.ColumnName = {'Layer'; 'Height'; 'Mass Density'; 'Angle (rad)'; 'nu12'; 'nu21'; 'E1'; 'E2'; 'G12'; 'G13'; 'G23'};
            app.tbl_layers.RowName = {};
            app.tbl_layers.ColumnEditable = true;
            app.tbl_layers.CellEditCallback = createCallbackFcn(app, @tbl_layersCellEdit, true);
            app.TNTTab = uitab(app.TabGroup);
            app.TNTTab.Title = 'TNT';
            app.txt_im = uieditfield(app.TNTTab, 'numeric');
            app.txt_im.Limits = [0 Inf];
            app.txt_im.Value = 40.536;
            app.txt_id = uieditfield(app.TNTTab, 'numeric');
            app.txt_id.Limits = [0 Inf];
            app.txt_id.Value = 23.2235;
            app.txt_td = uieditfield(app.TNTTab, 'numeric');
            app.txt_td.Limits = [0 1];
            app.txt_td.Value = 0.0018;
            app.txt_pmin = uieditfield(app.TNTTab, 'numeric');
            app.txt_pmin.Limits = [0 Inf];
            app.txt_pmin.Value = 15.420247;
            app.txt_pmax = uieditfield(app.TNTTab, 'numeric');
            app.txt_pmax.Limits = [0 Inf];
            app.txt_pmax.Value = 28900;
            app.txt_Z = uieditfield(app.TNTTab, 'numeric');
            app.txt_Z.Limits = [0.0001 38];
            app.txt_Z.Value = 8.54;
            app.txt_W = uieditfield(app.TNTTab, 'numeric');
            app.txt_W.Limits = [0.0001 Inf];
            app.txt_W.Value = 0.112;
            app.NegativeImpulsePasLabel = uilabel(app.TNTTab);
            app.NegativeImpulsePasLabel.Text = 'Negative Impulse (Pa.s):';
            app.PositiveImpulsePasLabel = uilabel(app.TNTTab);
            app.PositiveImpulsePasLabel.Text = 'Positive Impulse (Pa.s):';
            app.PositivetimesLabel = uilabel(app.TNTTab);
            app.PositivetimesLabel.Text = 'Positive time (s):';
            app.MaxUnderpressurePaLabel = uilabel(app.TNTTab);
            app.MaxUnderpressurePaLabel.Text = 'Max Underpressure (Pa):';
            app.MaxSobrepressurePaLabel = uilabel(app.TNTTab);
            app.MaxSobrepressurePaLabel.Text = 'Max Sobrepressure (Pa):';
            app.ScalarDistanceZkgm13Label = uilabel(app.TNTTab);
            app.ScalarDistanceZkgm13Label.Text = 'Scalar Distance Z (kg/m^1/3):';
            app.TotalMasskgLabel = uilabel(app.TNTTab);
            app.TotalMasskgLabel.Text = 'Total Mass (kg):';
            app.CharacteristicsLabel = uilabel(app.TNTTab);
            app.CharacteristicsLabel.Text = 'Characteristics';
            app.cmb_blast_equation = uidropdown(app.TNTTab);
            app.cmb_blast_equation.Items = {'Friedlander + Cubic Equation - Rigby Calibration', 'Friedlander + Cubic Equation (calculate tm) - Rigby Calibration', 'Friedlander + Cubic Equation (calculate tm) - Ana Calibration', 'Friedlander + Cubic Equation - Experimental Data', 'Expanded Friedlander - Rigby Calibration', 'Expanded Friedlander - Experimental Data'};
            app.cmb_blast_equation.Value = 'Expanded Friedlander - Experimental Data';
            app.TypeofEquationLabel = uilabel(app.TNTTab);
            app.TypeofEquationLabel.Text = 'Type of Equation';
            app.Panel = uipanel(app.TNTTab);
            app.TypeofExplosionLabel = uilabel(app.Panel);
            app.TypeofExplosionLabel.Text = 'Type of Explosion';
            app.TypeExplosion = uibuttongroup(app.Panel);
            app.SphericalButton = uiradiobutton(app.TypeExplosion);
            app.SphericalButton.Text = 'Spherical';
            app.SphericalButton.Value = true;
            app.HemisphericalButton = uiradiobutton(app.TypeExplosion);
            app.HemisphericalButton.Text = 'Hemispherical';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.GeneralAnalysisPanel = uipanel(app.AnalysisTab);
            app.GeneralAnalysisPanel.Title = 'General Analysis';
            app.timesLabel = uilabel(app.GeneralAnalysisPanel);
            app.timesLabel.Text = 'time (s):';
            app.txt_timeanalysis = uieditfield(app.GeneralAnalysisPanel, 'numeric');
            app.txt_timeanalysis.Limits = [0 Inf];
            app.txt_timeanalysis.Value = 0.08;
            app.TimeofAnalysisLabel = uilabel(app.GeneralAnalysisPanel);
            app.TimeofAnalysisLabel.Text = 'Time of Analysis';
            app.cmb_dynamic = uidropdown(app.GeneralAnalysisPanel);
            app.cmb_dynamic.Items = {'On', 'Off'};
            app.cmb_dynamic.Value = 'On';
            app.DynamicAnalysisLabel_2 = uilabel(app.GeneralAnalysisPanel);
            app.DynamicAnalysisLabel_2.Text = 'Dynamic Analysis';
            app.NegativePhase = uibuttongroup(app.GeneralAnalysisPanel);
            app.YesButton = uiradiobutton(app.NegativePhase);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.NegativePhase);
            app.NoButton.Text = 'No';
            app.NegativePhaseLabel = uilabel(app.GeneralAnalysisPanel);
            app.NegativePhaseLabel.Text = 'Negative Phase';
            app.cmb_type_analysis = uidropdown(app.GeneralAnalysisPanel);
            app.cmb_type_analysis.Items = {'CLPT', 'FSPT', 'TSPT', 'TvK', 'TSPT - FD'};
            app.cmb_type_analysis.Value = 'CLPT';
            app.TypeofAnalysisLabel_2 = uilabel(app.GeneralAnalysisPanel);
            app.TypeofAnalysisLabel_2.Text = 'Type of Analysis';
            app.btn_RunButton_General = uibutton(app.GeneralAnalysisPanel, 'push');
            app.btn_RunButton_General.ButtonPushedFcn = createCallbackFcn(app, @btn_RunButton_GeneralPushed, true);
            app.btn_RunButton_General.Text = 'Dynamic Analysis';
            app.AdvancedAnalysisPanel = uipanel(app.AnalysisTab);
            app.AdvancedAnalysisPanel.Title = 'Advanced Analysis';
            app.mass_interval = uibuttongroup(app.AdvancedAnalysisPanel);
            app.W1to10kgButton = uiradiobutton(app.mass_interval);
            app.W1to10kgButton.Text = 'W = 1 to 10 kg';
            app.W1to10kgButton.Value = true;
            app.W100to1000kgButton = uiradiobutton(app.mass_interval);
            app.W100to1000kgButton.Text = 'W = 100 to 1000 kg';
            app.txt_W_final = uieditfield(app.AdvancedAnalysisPanel, 'numeric');
            app.txt_W_final.Limits = [0 Inf];
            app.txt_interval = uieditfield(app.AdvancedAnalysisPanel, 'numeric');
            app.txt_interval.Limits = [0 Inf];
            app.txt_interval_case2 = uilabel(app.AdvancedAnalysisPanel);
            app.txt_interval_case2.Text = 'Number of intervals:';
            app.txt_mass_case2 = uilabel(app.AdvancedAnalysisPanel);
            app.txt_mass_case2.Text = 'Final TNT''s mass (kg):';
            app.cmb_case = uidropdown(app.AdvancedAnalysisPanel);
            app.cmb_case.Items = {'Case 1 - Z x uz / h', 'Case 2 - W x uz / h', 'Case 3 - td / TL x FAD - Variating W', 'Case 4 - td / TL x uz / h', 'Case 5 - td / TNL x uz / h', 'Case 6 - uz / h x stress', 'Case 7 - td / TL x FAD - Variating Z', 'Case 8 - General Equation'};
            app.cmb_case.Value = 'Case 1 - Z x uz / h';
            app.CaseofAnalysisLabel = uilabel(app.AdvancedAnalysisPanel);
            app.CaseofAnalysisLabel.Text = 'Case of Analysis';
            app.RunButton_Adv = uibutton(app.AdvancedAnalysisPanel, 'push');
            app.RunButton_Adv.ButtonPushedFcn = createCallbackFcn(app, @RunButton_AdvPushed, true);
            app.RunButton_Adv.Text = 'Advanced Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end